classdef SSGController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Button                     matlab.ui.control.StateButton
        TextArea                   matlab.ui.control.TextArea
        POWERSpinnerLabel          matlab.ui.control.Label
        POWERSpinner               matlab.ui.control.Spinner
        DWELLSpinnerLabel          matlab.ui.control.Label
        DWELLSpinner               matlab.ui.control.Spinner
        TEMPERATUREEditFieldLabel  matlab.ui.control.Label
        TEMPERATUREEditField       matlab.ui.control.NumericEditField
        Gauge                      matlab.ui.control.LinearGauge
        RFSwitchLabel              matlab.ui.control.Label
        RFSwitch                   matlab.ui.control.Switch
        EditField                  matlab.ui.control.EditField
        FREQUENCYDropDownLabel     matlab.ui.control.Label
        FREQUENCYDropDown          matlab.ui.control.DropDown
        Lamp                       matlab.ui.control.Lamp
        AMPLIFIERDropDownLabel     matlab.ui.control.Label
        AMPLIFIERDropDown          matlab.ui.control.DropDown
    end

    properties (Access = private)
        library = NET.addAssembly('...\mcl_gen64.dll'); % Import signal generator function library
        sg = mcl_gen64.usb_gen; % create signal generator object
        running; % create property to assign value for activity
        l1 = 1575.42; % create property for L1 frequency
        l2 = 1227.6; % create property for L2 frequency
        log; % create property for logging actions
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             Initialize properties
            app.running = 0;
            app.l1 = 1575.42;
            app.l2 = 1227.6;
            
%             Initialize components
%             app.RFSwitch.
%                 Connect button
            app.Button.Value = 0;
            app.Button.Text = 'CONNECT';
            app.Button.BackgroundColor = [0 1 0];
            
%                 RF switch
            app.RFSwitch.Items = {'OFF', 'ON'};
            app.RFSwitch.Value = 'OFF';
            app.RFSwitch.Enable = 'off';
            app.RFSwitchLabel.Enable = 'off';
            app.Lamp.Color = [1 0 0];
            app.Lamp.Enable = 'off';
            
%                 Frequency drop down
            app.FREQUENCYDropDown.Items = {'L1'; 'L2'; 'Hop'; 'Sweep'};
            app.FREQUENCYDropDown.Editable = 'off';
            app.FREQUENCYDropDown.Enable = 'off';
            app.FREQUENCYDropDownLabel.Enable = 'off';
            app.FREQUENCYDropDown.ItemsData = [app.l1 app.l2 3 4];
            
%                 Power spinner
            %{
            app.POWERSpinner.Value = -6;
            app.POWERSpinner.Limits = [-30, 0];
            %}
            app.POWERSpinner.Step = 1; %0.25;
            app.POWERSpinner.ValueDisplayFormat = '%.2f dBm';
            app.POWERSpinner.Editable = 'off';
            app.POWERSpinner.Enable = 'off';
            app.POWERSpinnerLabel.Enable = 'off';
            
%                 Dwell spinner
            app.DWELLSpinner.Value = 20;
            app.DWELLSpinner.Limits = [20, 10000];
            app.DWELLSpinner.Step = 10; %1;
            app.DWELLSpinner.ValueDisplayFormat = '%.0f ms';
            app.DWELLSpinner.Editable = 'off';
            app.DWELLSpinner.Enable = 'off';
            app.DWELLSpinnerLabel.Enable = 'off';
            
%                 Temperature
            app.TEMPERATUREEditField.Value = 0;
            app.TEMPERATUREEditField.Editable = 'off';
            app.TEMPERATUREEditField.ValueDisplayFormat = '%.0f °F';
            app.TEMPERATUREEditField.Enable = 'off';
            app.TEMPERATUREEditFieldLabel.Enable = 'off';
            app.Gauge.Enable = 'off';
            
%                 System drop down
            app.AMPLIFIERDropDown.Items = {' '; '5 W'; '30 W'};
            app.AMPLIFIERDropDown.Editable = 'off';
            app.AMPLIFIERDropDown.Enable = 'off';
            app.AMPLIFIERDropDownLabel.Enable = 'off';
            app.AMPLIFIERDropDown.ItemsData = [0 5 30];
            
%                 Text area
            app.TextArea.Editable = 'off';
            app.log = ["Press 'CONNECT' button to connect to signal generator"];
            app.TextArea.Value = app.log;
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
%             Check button state
%                 Unpressed button state
            if app.Button.Value == 0
                app.sg.SetPowerOFF;
                app.sg.Disconnect; % disconnect from signal generator
                app.running = 0; % assign inactive activity value
                app.Button.Text = 'CONNECT'; % assign connection button text
                app.Button.BackgroundColor = [0 1 0]; % assign connection button background color
                app.log(length(app.log) + 1) = ["DISCONNECTED"]; % log entry
%                     Disable components after disconnecting from signal
%                     generator
                app.RFSwitch.Enable = 'off'; % disable RF switch
                app.RFSwitchLabel.Enable = 'off';
                app.FREQUENCYDropDown.Enable = 'off';
                app.FREQUENCYDropDownLabel.Enable = 'off';
                app.POWERSpinner.Editable = 'off'; % turn off power spinner editability
                app.POWERSpinner.Enable = 'off'; % disable power spinner
                app.POWERSpinnerLabel.Enable = 'off';
                app.DWELLSpinner.Editable = 'off'; % turn off dwell spinner editability
                app.DWELLSpinner.Enable = 'off'; % disable dwell spinner
                app.DWELLSpinnerLabel.Enable = 'off';
                app.TEMPERATUREEditField.Enable = 'off';
                app.TEMPERATUREEditFieldLabel.Enable = 'off';
                app.Gauge.Enable = 'off';
                app.EditField.Enable = 'off';
                app.Lamp.Enable = 'off';
                app.AMPLIFIERDropDown.Enable = 'off';
                app.AMPLIFIERDropDownLabel.Enable = 'off';
                
%                 Pressed button state
            elseif app.Button.Value == 1                    
%                     Connect to signal generator
%                         Failure to connect
                        app.running = 1; % assign running property as active
                        app.Button.Text = 'DISCONNECT'; % reassign button text
                        app.Button.BackgroundColor = [1 0 0]; % reassign button color
                        app.log(length(app.log) + 1) = ["Connection SUCCESSFUL"]; % log entry
%                         Enable amplifier drop down
                        app.AMPLIFIERDropDown.Enable = 'on';
                        app.AMPLIFIERDropDownLabel.Enable = 'on';
                        
%                             Enable components
                        app.FREQUENCYDropDown.Enable = 'on';
                        app.FREQUENCYDropDownLabel.Enable = 'on';
                        app.DWELLSpinner.Editable = 'on'; % turn on dwell spinner editability
                        app.DWELLSpinner.Enable = 'on'; % enable dwell spinner
                        app.DWELLSpinnerLabel.Enable = 'on'; % enable dwell spinner label
                        app.TEMPERATUREEditField.Enable = 'on'; % enable temperature edit field
                        app.TEMPERATUREEditFieldLabel.Enable = 'on'; % enable temperature edit field label
                        app.Gauge.Enable = 'on'; % enable gauge
                        app.EditField.Enable = 'on'; % enable edit field
%                             Step through controller component functions
                        app.RFSwitchValueChanged; % RF
                        app.FREQUENCYDropDownValueChanged; % Frequency
                        app.DWELLSpinnerValueChanged; % Dwell time
                        app.log(length(app.log) + 1) = ["Select appropriate AMPLIFIER"]; % log entry
                        msgbox('Inspect the system to confirm the AMPLIFIER type. Selecting the incorrect AMPLIFIER could damage/destroy the system. RF and POWER will be enabled after selecting the AMPLIFIER.', 'WARNING');
            end
            
            while app.running == 1 % condition for actively running
                
                if app.Button.Value == 0 % disconnected
                    break
                elseif app.Button.Value == 1 % connected
                    
%                     if app.sg.Check_Connection == 0
%                         app.Button.Value = 0;
%                         app.ButtonValueChanged;
%                         break;
%                     end
                    
                    app.TEMPERATUREEditField.Value = double(0); % request temperature
                    app.Gauge.Value = app.TEMPERATUREEditField.Value; % assign gauge value
                    
                    if app.TEMPERATUREEditField.Value < 32
%                         msgbox('Signal generator EXCEEDED operating temperatures.', 'WARNING');
                        app.EditField.Value = 'OVERCOOLED';
                        app.EditField.BackgroundColor = [0 0 1];
                    elseif app.TEMPERATUREEditField.Value > 32 && app.TEMPERATUREEditField.Value < 42
%                         msgbox('Signal generator APPROACHING extreme temperatures.', 'WARNING');
                        app.EditField.Value = 'COOL';
                        app.EditField.BackgroundColor = [0 0 1];
                    elseif app.TEMPERATUREEditField.Value  > 42 && app.TEMPERATUREEditField.Value < 112
                        app.EditField.Value = 'NOMINAL';
                        app.EditField.BackgroundColor = [0 1 0];
                    elseif app.TEMPERATUREEditField.Value > 112 && app.TEMPERATUREEditField.Value < 122
%                         msgbox('Signal generator APPROACHING extreme temperatures.', 'WARNING');
                        app.EditField.Value = 'HOT';
                        app.EditField.BackgroundColor = [1 0 0];
                    elseif app.TEMPERATUREEditField.Value > 122
%                         msgbox('Signal generator EXCEEDED operating temperatures.', 'WARNING');
                        app.EditField.Value = 'OVERHEATED';
                        app.EditField.BackgroundColor = [1 0 0];
                    end
                    app.TextArea.Value = app.log;
                    pause(1); % call for pause to prevent MATLAB from crashing
                end
                
            end
            app.TextArea.Value = app.log; % output entries to text area
        end

        % Value changed function: POWERSpinner
        function POWERSpinnerValueChanged(app, event)
            app.POWERSpinner.Value = round(app.POWERSpinner.Value); 
            app.sg.SetPower(app.POWERSpinner.Value, 0); % set power
            app.log(length(app.log) + 1) = [sprintf("POWER set to %.2f dBm", app.POWERSpinner.Value)]; % log entry
            app.TextArea.Value = app.log; % output entries to text area
        end

        % Value changed function: DWELLSpinner
        function DWELLSpinnerValueChanged(app, event)
            app.DWELLSpinner.Value = round(app.DWELLSpinner.Value, -1);
            app.log(length(app.log) + 1) = [sprintf("DWELL time set to %d ms", app.DWELLSpinner.Value)]; % log entry
            app.TextArea.Value = app.log; % output entries to text area
        end

        % Value changed function: RFSwitch
        function RFSwitchValueChanged(app, event)
            if app.RFSwitch.Value == "OFF" % OFF position
                app.sg.SetPowerOFF; % turn off RF power
                app.log(length(app.log) + 1) = ["RF is OFF"]; % log entry    
                app.Lamp.Color = [1 0 0]; % change lamp color to red
            elseif app.RFSwitch.Value == "ON" % ON position
                app.sg.SetPowerON; % turn on RF power
                app.log(length(app.log) + 1) = ["RF is ON"]; % log entry
                app.Lamp.Color = [0 1 0]; % change lamp color to green
            end
            app.TextArea.Value = app.log; % output entries to text area
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.sg.SetPowerOFF; % turn off RF upon close request window
            app.sg.Disconnect; % disconnect from signal generator upon close request window
            delete(app); % close window
        end

        % Value changed function: FREQUENCYDropDown
        function FREQUENCYDropDownValueChanged(app, event)
            if app.FREQUENCYDropDown.Value == app.l1 % L1 button
                app.sg.SetFreq(app.l1, 0); % set frequency to L1
                app.log(length(app.log) + 1) = [sprintf("FREQUENCY set to L1 (%.2f MHz)", app.l1)]; % log entry
            elseif app.FREQUENCYDropDown.Value == app.l2 % L2 button
                app.sg.SetFreq(app.l2, 0); % set frequency to L2
                app.log(length(app.log) + 1) = [sprintf("FREQUENCY set to L2 (%.2f MHz)", app.l2)]; % log entry
            elseif app.FREQUENCYDropDown.Value == 3 % Hop button
                app.log(length(app.log) + 1) = ["FREQUENCY set to HOP from L1 and L2"]; % log entry
                app.TextArea.Value = app.log; % output entries to text area
                while app.FREQUENCYDropDown.Value == 3 % hop loop
                    app.sg.SetFreq(app.l1, 0); % first frequency to hop
                    pause(app.DWELLSpinner.Value/1000); % pause for dwell time
                    app.sg.SetFreq(app.l2, 0); % second frequency to hop
                    pause(app.DWELLSpinner.Value/1000); % pause for dwell time
                    if app.running == 1 % run check-up
                        if app.sg.Check_Connection == 0 % case for disconnection
                            app.Button.Value = 0; % set button to unpressed position
                            app.ButtonValueChanged; % execute button value changed callback
                            break; % break from hop while loop
                        end
                        app.TEMPERATUREEditField.Value = double(app.sg.GetDeviceTemperature * 9 / 5 + 32); % request temperature
                        app.Gauge.Value = app.TEMPERATUREEditField.Value; % assign gauge value
                    end
                end
            elseif app.FREQUENCYDropDown.Value == 4 % sweep loop
                app.log(length(app.log) + 1) = ["FREQUENCY set to SWEEP L1, L2 and 1525 - 1569 MHz"]; % log entry
                app.TextArea.Value = app.log; % 
                i = 0;
                while app.FREQUENCYDropDown.Value == 4
                    app.sg.SetFreq(1525 + i, 0);
                    pause(app.DWELLSpinner.Value/1000);
                    if 1525 + i == 1569
                        app.sg.SetFreq(1575.42, 0)
                        pause(app.DWELLSpinner.Value/1000);
                        app.sg.SetFreq(1227.6, 0);
                        pause(app.DWELLSpinner.Value/1000);
                        i = 0;
                    end
                    i = i + 1;
                    if app.running == 1
                        if app.sg.Check_Connection == 0
                            app.Button.Value = 0;
                            app.ButtonValueChanged;
                            break;
                        end
                        app.TEMPERATUREEditField.Value = double(app.sg.GetDeviceTemperature * 9 / 5 + 32); % request temperature
                        app.Gauge.Value = app.TEMPERATUREEditField.Value; % assign gauge value
                    end
                end
            end
            app.TextArea.Value = app.log; % output entries to text area
        end

        % Value changed function: AMPLIFIERDropDown
        function AMPLIFIERDropDownValueChanged(app, event)
%             value = app.AMPLIFIERDropDown.Value;
            if app.AMPLIFIERDropDown.Value == 0
%                 set safe conditions for either amp
                
%                 Disable all components
                app.RFSwitch.Enable = 'off'; % disable RF switch
                app.RFSwitchLabel.Enable = 'off';
                app.POWERSpinner.Editable = 'off'; % turn off power spinner editability
                app.POWERSpinner.Enable = 'off'; % disable power spinner
                app.POWERSpinnerLabel.Enable = 'off';
                app.Lamp.Enable = 'off';
%                 Turn RF off
                app.sg.SetPowerOFF;
                app.RFSwitch.Value = 'OFF';
                app.RFSwitchValueChanged;
            elseif app.AMPLIFIERDropDown.Value == 5
%                 set 5W conditions
                app.POWERSpinner.Value = -14;
                app.POWERSpinner.Limits = [-30 -14];
%                 Enable all components
                app.RFSwitch.Enable = 'on'; % enable RF switch
                app.RFSwitchLabel.Enable = 'on'; % enable RF switch label
                app.POWERSpinner.Editable = 'on'; % turn off power spinner editability
                app.POWERSpinner.Enable = 'on'; % enable power spinner
                app.POWERSpinnerLabel.Enable = 'on'; % enable power spinner label
                app.Lamp.Enable = 'on'; % enable lamp
%                 Step through controller component functions
                app.log(length(app.log) + 1) = ["5 Watt AMPLIFIER selected"]; % log entry
                app.TextArea.Value = app.log;
                app.RFSwitchValueChanged; % RF
                app.POWERSpinnerValueChanged; % Power
            elseif app.AMPLIFIERDropDown.Value == 30
%                 set 30W conditons
                app.POWERSpinner.Limits = [-30 -13];
%                 Enable all components
                app.RFSwitch.Enable = 'on'; % enable RFapp.AMPLIFIERDropDown switch
                app.RFSwitchLabel.Enable = 'on'; % enable RF switch label
                app.POWERSpinner.Editable = 'on'; % turn off power spinner editability
                app.POWERSpinner.Enable = 'on'; % enable power spinner
                app.POWERSpinnerLabel.Enable = 'on'; % enable power spinner label
                app.Lamp.Enable = 'on'; % enable lamp
%                 Step through controller component functions
                app.log(length(app.log) + 1) = ["30 Watt AMPLIFIER selected"]; % log entry
                app.TextArea.Value = app.log;
                app.RFSwitchValueChanged; % RF
                app.POWERSpinnerValueChanged; % Power
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.TextArea = uitextarea(app.UIFigure);
            app.POWERSpinnerLabel = uilabel(app.UIFigure);
            app.POWERSpinnerLabel.Text = 'POWER';
            app.POWERSpinner = uispinner(app.UIFigure);
            app.POWERSpinner.ValueChangedFcn = createCallbackFcn(app, @POWERSpinnerValueChanged, true);
            app.DWELLSpinnerLabel = uilabel(app.UIFigure);
            app.DWELLSpinnerLabel.Text = 'DWELL';
            app.DWELLSpinner = uispinner(app.UIFigure);
            app.DWELLSpinner.ValueChangedFcn = createCallbackFcn(app, @DWELLSpinnerValueChanged, true);
            app.TEMPERATUREEditFieldLabel = uilabel(app.UIFigure);
            app.TEMPERATUREEditFieldLabel.Text = 'TEMPERATURE';
            app.TEMPERATUREEditField = uieditfield(app.UIFigure, 'numeric');
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.Gauge.Limits = [0 150];
            app.Gauge.MajorTicks = [0 32 50 75 100 122 150];
            app.RFSwitchLabel = uilabel(app.UIFigure);
            app.RFSwitchLabel.Text = 'RF';
            app.RFSwitch = uiswitch(app.UIFigure, 'slider');
            app.RFSwitch.ValueChangedFcn = createCallbackFcn(app, @RFSwitchValueChanged, true);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.FREQUENCYDropDownLabel = uilabel(app.UIFigure);
            app.FREQUENCYDropDownLabel.Text = 'FREQUENCY';
            app.FREQUENCYDropDown = uidropdown(app.UIFigure);
            app.FREQUENCYDropDown.ValueChangedFcn = createCallbackFcn(app, @FREQUENCYDropDownValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.AMPLIFIERDropDownLabel = uilabel(app.UIFigure);
            app.AMPLIFIERDropDownLabel.Text = 'AMPLIFIER';
            app.AMPLIFIERDropDown = uidropdown(app.UIFigure);
            app.AMPLIFIERDropDown.ValueChangedFcn = createCallbackFcn(app, @AMPLIFIERDropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SSGController

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end